classdef prototypeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FotokiezenButton               matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        CroppedTab                     matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        PaarsTab                       matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        GeelTab                        matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        PercentageGeelEditFieldLabel   matlab.ui.control.Label
        PercentageGeelEditField        matlab.ui.control.NumericEditField
        PercentagePaarsEditFieldLabel  matlab.ui.control.Label
        PercentagePaarsEditField       matlab.ui.control.NumericEditField
        OpslaanalsDropDownLabel        matlab.ui.control.Label
        OpslaanalsDropDown             matlab.ui.control.DropDown
        OpslaanButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        croppedImage 
    end


    methods (Access = private)

        % Button pushed function: FotokiezenButton
        function FotokiezenButtonPushed(app, event)
            [file,path]=uigetfile({'*.*',  'All Files (*.*)'}, 'Select file');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if file ~= 0
                progressBar = waitbar(0, 'Loading your blood clot');
                Im = imread(file);
                imshow(imresize(Im, 0.25), 'Parent', app.UIAxes_4)
                %figure
                gray = rgb2gray(Im);
                %imshow(gray)
                mask = gray > 200;
                gray(mask) = 0;
                gray(~mask) = 255;
                %imshow(gray)
                fillHoles = imfill(gray,'holes');
                %imshow(fillHoles)
                %figure
                beter = bwareaopen(fillHoles, 1500);
                %imshow(beter)
                %figure
                
                waitbar(0.25, progressBar, 'Loading your blood clot')
                
                r = Im(:,:,1);
                g = Im(:,:,2);
                b = Im(:,:,3);
                r(beter==0) = 255;
                g(beter==0) = 255;
                b(beter==0) = 255;
                Im(:,:,1) = r;
                Im(:,:,2) = g;
                Im(:,:,3) = b;
                
                %imshow(Im)
                %figure
                
                [height, width] = size(beter);
                buffer = 300;
                
                waitbar(0.66, progressBar, 'Loading your blood clot')
                
                [rows, columns] = find(beter);
                row1 = max([min(rows) - buffer, 1]);
                row2 = min([max(rows) + buffer, height]);
                col1 = max([min(columns) - buffer, 1]);
                col2 = min([max(columns) + buffer, width]);
                
                waitbar(1, progressBar, 'Loading your blood clot')
                close(progressBar)
                
                app.croppedImage = Im(row1:row2, col1:col2,:);
                imshow(app.croppedImage, 'Parent', app.UIAxes_3)


             end
        end

        % Button pushed function: OpslaanButton
        function OpslaanButtonPushed(app, event)

            if app.OpslaanalsDropDown.Value == 'Hoge resolutie'
                imwrite(app.croppedImage, 'testOpslaanButtonHoog.jpg', 'Quality', 100)
            else
                imwrite(app.croppedImage, 'testOpslaanButtonLaag.jpg', 'Quality', 5)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FotokiezenButton = uibutton(app.UIFigure, 'push');
            app.FotokiezenButton.ButtonPushedFcn = createCallbackFcn(app, @FotokiezenButtonPushed, true);
            app.FotokiezenButton.Text = 'Foto kiezen';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CroppedTab = uitab(app.TabGroup);
            app.CroppedTab.Title = 'Cropped';
            app.UIAxes_3 = uiaxes(app.CroppedTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.PaarsTab = uitab(app.TabGroup);
            app.PaarsTab.Title = 'Paars';
            app.UIAxes_2 = uiaxes(app.PaarsTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.GeelTab = uitab(app.TabGroup);
            app.GeelTab.Title = 'Geel';
            app.UIAxes = uiaxes(app.GeelTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.PercentageGeelEditFieldLabel = uilabel(app.UIFigure);
            app.PercentageGeelEditFieldLabel.Text = 'Percentage Geel';
            app.PercentageGeelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentageGeelEditField.Editable = 'off';
            app.PercentagePaarsEditFieldLabel = uilabel(app.UIFigure);
            app.PercentagePaarsEditFieldLabel.Text = 'Percentage Paars';
            app.PercentagePaarsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentagePaarsEditField.Editable = 'off';
            app.OpslaanalsDropDownLabel = uilabel(app.UIFigure);
            app.OpslaanalsDropDownLabel.Text = 'Opslaan als';
            app.OpslaanalsDropDown = uidropdown(app.UIFigure);
            app.OpslaanalsDropDown.Items = {'Hoge resolutie', 'Lage resolutie', 'Option 3', 'Option 4'};
            app.OpslaanalsDropDown.Value = 'Hoge resolutie';
            app.OpslaanButton = uibutton(app.UIFigure, 'push');
            app.OpslaanButton.ButtonPushedFcn = createCallbackFcn(app, @OpslaanButtonPushed, true);
            app.OpslaanButton.Text = 'Opslaan';
        end
    end

    methods (Access = public)

        % Construct app
        function app = prototypeApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end